classdef BurstDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BurstDetectionUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FileSelectionTab                matlab.ui.container.Tab
        ExcludedChannelsListBox_2       matlab.ui.control.ListBox
        ChannelsIncludedinresultsLabel  matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ChannelsnotusedforBurstDetectionListBox  matlab.ui.control.ListBox
        ChannelsnotusedforBurstDetectionListBoxLabel  matlab.ui.control.Label
        ChannelsusedforBurstDetectionListBox  matlab.ui.control.ListBox
        ChannelsusedforBurstDetectionLabel  matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        FileSelectionPanel              matlab.ui.container.Panel
        SelectFileButton                matlab.ui.control.Button
        DataFileEditField               matlab.ui.control.EditField
        RunButton_4                     matlab.ui.control.Button
        TimeWindowsecPanel              matlab.ui.container.Panel
        EndEditField_3                  matlab.ui.control.NumericEditField
        EndEditField_3Label             matlab.ui.control.Label
        StartEditField_3                matlab.ui.control.NumericEditField
        StartEditField_3Label           matlab.ui.control.Label
        BD_2                            matlab.ui.control.UIAxes
        WindowSizeTab                   matlab.ui.container.Tab
        ResetButton_2                   matlab.ui.control.Button
        MinSpikeswindowPanel            matlab.ui.container.Panel
        EndEditField_2                  matlab.ui.control.NumericEditField
        EndEditField_2Label             matlab.ui.control.Label
        StepEditField_2                 matlab.ui.control.NumericEditField
        StepEditField_2Label            matlab.ui.control.Label
        StartEditField_2                matlab.ui.control.NumericEditField
        StartEditField_2Label           matlab.ui.control.Label
        Steps10sPanel                   matlab.ui.container.Panel
        EndEditField                    matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        ISIN                            matlab.ui.control.UIAxes
        BurstDetectionPlotTab           matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        EpisodeAnalysisCheckBox         matlab.ui.control.CheckBox
        EpisodeMax                      matlab.ui.control.NumericEditField
        EpisodeISI                      matlab.ui.control.NumericEditField
        MaxinterspikesLabel             matlab.ui.control.Label
        minmaxLabel                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        WindowsizesecLabel              matlab.ui.control.Label
        EpisodeMin                      matlab.ui.control.NumericEditField
        ProcessingStepsButtonGroup      matlab.ui.container.ButtonGroup
        DoneButton                      matlab.ui.control.RadioButton
        PreparingsecondplotButton       matlab.ui.control.RadioButton
        PreparingfirstplotButton        matlab.ui.control.RadioButton
        CalculatingnumberofBurstsButton  matlab.ui.control.RadioButton
        StartedButton                   matlab.ui.control.RadioButton
        SavedEditField                  matlab.ui.control.NumericEditField
        SavedEditFieldLabel             matlab.ui.control.Label
        NbBurstsEditField               matlab.ui.control.NumericEditField
        NbBurstsEditFieldLabel          matlab.ui.control.Label
        SaveResultsButton               matlab.ui.control.Button
        RunButton_3                     matlab.ui.control.Button
        BurstCriteriaPanel              matlab.ui.container.Panel
        MinchannelsEditField            matlab.ui.control.NumericEditField
        MinchannelsEditFieldLabel       matlab.ui.control.Label
        TimeWindowsecEditField          matlab.ui.control.NumericEditField
        TimeWindowsecEditFieldLabel     matlab.ui.control.Label
        SpikesBurstEditField            matlab.ui.control.NumericEditField
        SpikesBurstEditFieldLabel       matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        SelectaTimestampDropDown        matlab.ui.control.DropDown
        Slider                          matlab.ui.control.Slider
        FinalPlot                       matlab.ui.control.UIAxes
        BD                              matlab.ui.control.UIAxes
        Episode                         matlab.ui.container.Tab
        MaxColorbarValueEditField       matlab.ui.control.NumericEditField
        MaxColorbarValueEditFieldLabel  matlab.ui.control.Label
        MinColorbarValueEditField       matlab.ui.control.NumericEditField
        MinColorbarValueEditFieldLabel  matlab.ui.control.Label
        SpikesEpisodePlot               matlab.ui.control.UIAxes
        Episode_plot                    matlab.ui.control.UIAxes
        DPlotTab                        matlab.ui.container.Tab
        MaxColorbarValueEditField_2     matlab.ui.control.NumericEditField
        MaxColorbarValueEditFieldLabel_2  matlab.ui.control.Label
        MinColorbarValueEditField_2     matlab.ui.control.NumericEditField
        MinColorbarValueEditFieldLabel_2  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    %  BurstDetection App
    % © Nicholas Au Yong and Zahraa Sabra, 2024
    %
    % ISI Burst Detection Methodology based on the following reference
    % Bakkum DJ, Radivojevic M, Frey U, Franke F, Hierlemann A, Takahashi H. Parameters for burst detection.
    % Front Comput Neurosci. 2014 Jan 13;7:193. doi: 10.3389/fncom.2013.00193. PMID: 24567714; PMCID: PMC3915237.
    
    properties (Access = public)
        NbBurstChannels;%within the specified window size
        new_Data; % Description
        Burst;
        Burst_Data;
        GlobalData;
        filteredData_All;
        filteredData;
        Detected;
        timeStamp;
        %% parameters to look for
        OutputData1=table;
        OutputData2=table;
        OutputData3=table;
        OutputData4=table;
        OutputData5=table;
        OutputData6=table;
        OutputData7=table;
        OutputData8=table;
        excludedChannels;
    end
    methods (Access = private)
        
        function BurstMain(app)


            app.StartedButton.Enable=true;
            app.StartedButton.Value=true;
            app.StartedButton.FontColor=[1,0,0];
            app.CalculatingnumberofBurstsButton.FontColor=[0,0,0];
            app.PreparingfirstplotButton.FontColor=[0,0,0];
            app.PreparingsecondplotButton.FontColor=[0,0,0];
            app.DoneButton.FontColor=[0,0,0];
            app.CalculatingnumberofBurstsButton.Enable=false;
            app.PreparingfirstplotButton.Enable=false;
            app.PreparingsecondplotButton.Enable=false;
            app.DoneButton.Enable=false;
            pause(1);
            app.OutputData1=table;
            app.OutputData2=table;
            app.OutputData3=table;
            app.OutputData4=table;
            app.OutputData5=table;
            app.OutputData6=table;
            app.OutputData7=table;
            app.OutputData8=table;
            app.new_Data=sortrows(app.filteredData,[1 2 3]);
            spikes.T=app.new_Data.ts;
            spikes.C=app.new_Data.chan;
            
            
            app.CalculatingnumberofBurstsButton.Enable=true;
            app.CalculatingnumberofBurstsButton.Value=true;
            
            app.StartedButton.FontColor=[0,0,0];
            app.CalculatingnumberofBurstsButton.FontColor=[1,0,0];
            app.PreparingfirstplotButton.FontColor=[0,0,0];
            app.PreparingsecondplotButton.FontColor=[0,0,0];
            app.DoneButton.FontColor=[0,0,0];
            app.StartedButton.Enable=false;
            app.PreparingfirstplotButton.Enable=false;
            app.PreparingsecondplotButton.Enable=false;
            app.DoneButton.Enable=false;
            pause(1);
            
            
            [app.Burst, SpikeBurstNumber] = BurstDetectISIn(spikes, app.SpikesBurstEditField.Value,app.TimeWindowsecEditField.Value);
            k1=[];
            for k2=1:length(app.Burst.C)
                if (app.Burst.C(k2)<app.MinchannelsEditField.Value)
                    k1=[k1;k2];
                end
            end
            app.Burst.C(k1)=[];
            app.Burst.S(k1)=[];
            app.Burst.T_start(k1)=[];
            app.Burst.T_end(k1)=[];
            
            if isempty(app.Burst)
                return;
                
            end
            selectedVals=app.ExcludedChannelsListBox_2.Items;
            
            
            if ~isempty(selectedVals)
                newVals=[];
                addDataNew=[];
                addData=app.filteredData;
                for i=1:length(selectedVals)
                    newVals(i)=str2num(selectedVals{i});
                    %use all data but keep the burst number the same
                    ind1=find(app.filteredData_All.chan==newVals(i));
                    if ~isempty(ind1)
                        addDataNew=app.filteredData_All(ind1,:);
                        addData=[addData;addDataNew];
                        addData=unique(addData,'rows');
                    end
                    
                end
                
                app.new_Data=sortrows(addData,[1 2 3]);
                spikes.T=app.new_Data.ts;
                spikes.C=app.new_Data.chan;
                SpikeBurstNumber = zeros(size(spikes.T)) - 1;
                for burst_ID=1:length(app.Burst.T_start)
                    ind1=find(spikes.T==app.Burst.T_start(burst_ID));
                    ind2=find(spikes.T==app.Burst.T_end(burst_ID));
                    SpikeBurstNumber(ind1(1):ind2(end))=burst_ID;
                    app.Burst.C(burst_ID)=length(unique(spikes.C(ind1(1):ind2(end))));
                    app.Burst.S(burst_ID)=length(spikes.C(ind1(1):ind2(end)));
                end
                
            end
            app.NbBurstsEditField.Value=length(app.Burst.T_start);
            
            app.PreparingfirstplotButton.Enable=true;
            app.PreparingfirstplotButton.Value=true;
            app.StartedButton.FontColor=[0,0,0];
            app.CalculatingnumberofBurstsButton.FontColor=[0,0,0];
            app.PreparingfirstplotButton.FontColor=[1,0,0];
            app.PreparingsecondplotButton.FontColor=[0,0,0];
            app.DoneButton.FontColor=[0,0,0];
            
            app.StartedButton.Enable=false;
            app.CalculatingnumberofBurstsButton.Enable=false;
            
            app.PreparingsecondplotButton.Enable=false;
            app.DoneButton.Enable=false;
            pause(1);
            
            % 1-Burst frequency
            TotalTime=max(app.new_Data.ts)-min(app.new_Data.ts);
            if max(SpikeBurstNumber)==-1
                app.OutputData1.BurstFreq=0;
            else
                app.OutputData1.BurstFreq=max(SpikeBurstNumber)/TotalTime;%how many Bursts per second
            end
            % 2-Duration of burst (time between first and last spike in a given burst)
            if app.EpisodeAnalysisCheckBox.Value
                app.OutputData2.EpidodeID=zeros(length(app.Burst.T_start),1);
            end
            app.OutputData2.BurstID= [1:length(app.Burst.T_start)]';
            app.OutputData2.BurstStart=app.Burst.T_start';
            app.OutputData2.BurstEnd=app.Burst.T_end';
            app.OutputData2.Duration=app.Burst.T_end'-app.Burst.T_start';
            
            % 3- Spike frequency (within a burst, in the inter burst period, and overall)
            
            app.OutputData2.NbSpikesForEachBurst=app.Burst.S';
            app.OutputData2.rateSpikesForEachBurst=app.Burst.S'./(app.Burst.T_end'-app.Burst.T_start');
            
            TotalBurstTime=sum(app.Burst.T_end'-app.Burst.T_start');
            app.OutputData1.rateSpikesWithinBurst=sum(app.Burst.S)/TotalBurstTime;
            app.OutputData1.rateSpikesInterBurst=(length(app.new_Data.ts)-sum(app.Burst.S))/(TotalTime-TotalBurstTime);
            app.OutputData1.rateSpikeOverall=length(app.new_Data.ts)/TotalTime;
            
            % 5- Number of channels involved in each burst
            app.OutputData2.NbChannelsForEachBurst=app.Burst.C';
            
            % 4- Spike frequency of individual channels within a burst
            
            app.Burst_Data=app.new_Data;
            app.Burst_Data.Burst=app.Burst_Data.chan*0;
            
            for i=1:length(app.Burst.T_start)
                app.Burst_Data.Burst((app.Burst_Data.ts>=app.Burst.T_start(i))&(app.Burst_Data.ts<=app.Burst.T_end(i)))=1;
            end
            
            ID = find(app.Burst.T_end<max(spikes.T));
            app.Detected = [];
            
            for i=ID
                app.Detected = [ app.Detected app.Burst.T_start(i) app.Burst.T_end(i) NaN ];
            end


            plotData(app,1,1);
            
            if app.EpisodeAnalysisCheckBox.Value
                if ~ isempty(app.Burst.C)
                    Episode_Window_min=app.EpisodeMin.Value;
                    Episode_Window_max=app.EpisodeMax.Value;
                    burstTimes=[ID' app.Burst.T_start' app.Burst.T_end'];
                    [burstTimes,newInd] = sortrows(burstTimes,2);
                    
                    End2Start=burstTimes(2:end,2)-burstTimes(1:end-1,3);
                    IncludeIndex=find(End2Start>10);
                    
                    newburstTime=[];
                    newburstTime(1,:)=[burstTimes(1,1) burstTimes(1,2) burstTimes(IncludeIndex(1),3)];
                    for k=2:length(IncludeIndex)
                        newburstTime(k,:)=[burstTimes(IncludeIndex(k-1)+1,1) burstTimes(IncludeIndex(k-1)+1,2) burstTimes(IncludeIndex(k),3)];
                        
                    end
                    newburstTime(end+1,:)=[burstTimes(IncludeIndex(k)+1,1) burstTimes(IncludeIndex(k)+1,2) burstTimes(end,3)];
                    
                    [diff1]=diff(newburstTime(:,2));
                    indDiff=find(diff1>Episode_Window_min & diff1<Episode_Window_max);
                    BurstTimeLen=newburstTime(:,3)-newburstTime(:,2);
                    indDiff_sameBurst=find(BurstTimeLen>Episode_Window_min & BurstTimeLen<Episode_Window_max);
                    

                     plotData(app,0,0);
                    
                    getEpisodes(app,newburstTime,spikes);
                    
                end
            end
        end
        
        function getEpisodes(app,burstTimes,spikes)
            Episode_Window_min=app.EpisodeMin.Value;
            Episode_Window_max=app.EpisodeMax.Value;

             EpidodeHeatMap=[];

                 finalCheck=1;
                while finalCheck<size(burstTimes,1)
                    calc1=burstTimes(finalCheck+1,3)- burstTimes(finalCheck,2);
                    
                    if (calc1<Episode_Window_max)
                        
                        burstTimes(finalCheck,3)=burstTimes(finalCheck+1,3);
                    
                        burstTimes(finalCheck+1,:)=[];
                    else
                        finalCheck=finalCheck+1;
                    end
                end
                newDuration=burstTimes(:,3)-burstTimes(:,2);
                indRemove=find(newDuration<Episode_Window_min);
                if (~isempty(indRemove))
                    burstTimes(indRemove,:)=[];
                end
                Episode_ID=1:size(burstTimes,1);
                Episode_Info=[];
                app.OutputData2.EpidodeID=zeros(length(app.OutputData2.BurstID),1);
                AllChannels=sort(unique(app.Burst_Data.chan));
                EpidodeHeatMap=zeros(length(Episode_ID),64);
                 Episode_Info=burstTimes;%startBurstID
                    
              for i=Episode_ID


                    indStrt=find(app.OutputData2.BurstStart==Episode_Info(i,2));
                    
                    
                    indEnd=find(app.OutputData2.BurstEnd==Episode_Info(i,3));
                    app.OutputData2.EpidodeID(indStrt(1):indEnd(1),1)=i;
                    indSpikes=find(spikes.T>=Episode_Info(i,2) & spikes.T<=Episode_Info(i,3) );
                    Episode_Info(i,4)=length(indSpikes);
                    Episode_Info(i,5)=Episode_Info(i,4)/(Episode_Info(i,3)-Episode_Info(i,2));
                    
                    EpisodeChannels=unique(spikes.C(indSpikes));
                    for j=1:length(EpisodeChannels)
                        spikesCount=find(EpisodeChannels(j)==spikes.C(indSpikes));
                        EpidodeHeatMap(i,EpisodeChannels(j))=length(spikesCount);
                        
                    end
                    %
                    % end
                    patch([Episode_Info(i,2) Episode_Info(i,3) Episode_Info(i,3) Episode_Info(i,2)], [min(app.new_Data.chan) min(app.new_Data.chan) max(app.new_Data.chan) max(app.new_Data.chan)], 'green', 'EdgeColor','green','FaceAlpha', 0.2,'parent', app.Episode_plot) ;  % <— ADD THIS LINE
                    
                    
                end
                app.OutputData7.EpisodeID=Episode_ID';
                app.OutputData7.StartTime=Episode_Info(:,2);
                app.OutputData7.EndTime=Episode_Info(:,3);
                app.OutputData7.EpisodeSpikes=Episode_Info(:,4);
                app.OutputData7.EpisodeSpikesRate=Episode_Info(:,5);
                app.OutputData8=EpidodeHeatMap;
                
                EpisodeIDVals=Episode_ID';
                
                channelIDVals=sort(unique(app.Burst_Data.chan));
                
                b= bar3(channelIDVals,EpidodeHeatMap(:,AllChannels)','detached','parent',app.SpikesEpisodePlot );
                
                for k = 1:length(b)
                    zdata = b(k).ZData;
                    b(k).CData = zdata;
                    b(k).FaceColor = 'interp';
                end
                app.MinColorbarValueEditField.Value=0;
                app.MaxColorbarValueEditField.Value=max(max(EpidodeHeatMap));
                
                caxis(app.SpikesEpisodePlot,[0 max(max(EpidodeHeatMap))]);
                cmap =  colormap(app.SpikesEpisodePlot,parula) ;   % change color map
                Nrepeats=floor(max(max(EpidodeHeatMap))/size(cmap,1));
                remaining=mod(max(max(EpidodeHeatMap)),size(cmap,1));
                if Nrepeats>1
                    cnt1=2;
                    
                    for iter=2:size(cmap,1)
                        cmap2(cnt1:(cnt1+Nrepeats),:)=repmat(cmap(iter,:),Nrepeats+1,1);
                        cnt1=cnt1+Nrepeats+1;
                    end
                    cmap2(end+1:end+remaining+1,:)=repmat(cmap(end,:),remaining+1,1);
                end
                
                cmap1(1,:)=[160 160 160]/256;
                if Nrepeats>1
                    cmap1(2:size(cmap2,1),:)=cmap2(2:end,:);
                else
                    cmap1(2:size(cmap,1),:)=cmap(2:end,:);
                    cmap1(end+1:end+remaining+1,:)=repmat(cmap(end,:),remaining+1,1);
                end
                colormap(app.SpikesEpisodePlot,cmap1);
                colorbar(app.SpikesEpisodePlot);
                
                
            %end
        end
        function  plotData(app,firstTime,flagBD)
            if ~flagBD
                hold(app.BD,'off');
                hold(app.Episode_plot,'on')
                plot(app.Burst_Data.ts(app.Burst_Data.Burst==0),app.Burst_Data.chan(app.Burst_Data.Burst==0), 'k.','parent',app.Episode_plot);
                
                plot(app.Burst_Data.ts(app.Burst_Data.Burst==1),app.Burst_Data.chan(app.Burst_Data.Burst==1), 'r.','parent',app.Episode_plot)
                plot( app.Detected, -1*ones(size(app.Detected)), 'r', 'linewidth', 4,'parent',app.Episode_plot )
                ylim(app.Episode_plot,[min(app.Burst_Data.chan) max(app.Burst_Data.chan)]);
                xlim(app.Episode_plot,[min(app.Burst_Data.ts) max(app.Burst_Data.ts)]);
                
            else
                app.PreparingfirstplotButton.Enable=true;
                app.PreparingfirstplotButton.Value=true;
                
                app.StartedButton.FontColor=[0,0,0];
                app.CalculatingnumberofBurstsButton.FontColor=[0,0,0];
                app.PreparingfirstplotButton.FontColor=[1,0,0];
                app.PreparingsecondplotButton.FontColor=[0,0,0];
                app.DoneButton.FontColor=[0,0,0];
                app.StartedButton.Enable=false;
                app.CalculatingnumberofBurstsButton.Enable=false;
                
                app.PreparingsecondplotButton.Enable=false;
                app.DoneButton.Enable=false;
                pause(1);
                
                plot(app.Burst_Data.ts(app.Burst_Data.Burst==0),app.Burst_Data.chan(app.Burst_Data.Burst==0), 'k.','parent',app.BD);
                hold(app.BD,'on')
                plot(app.Burst_Data.ts(app.Burst_Data.Burst==1),app.Burst_Data.chan(app.Burst_Data.Burst==1), 'r.','parent',app.BD)
                plot( app.Detected, -1*ones(size(app.Detected)), 'r', 'linewidth', 4,'parent',app.BD )
                ylim(app.BD,[min(app.Burst_Data.chan) max(app.Burst_Data.chan)]);
                xlim(app.BD,[min(app.Burst_Data.ts) max(app.Burst_Data.ts)]);
                
                if (length(app.Burst.T_start)>1000)
                    
                    f = errordlg('Please select a smaller portion of the file!','Huge Data','error');
                    
                    app.DoneButton.Value=true;
                    
                    app.PreparingfirstplotButton.Enable=false;
                    
                    app.DoneButton.Enable=false;
                    return;
                end
                app.PreparingsecondplotButton.Enable=true;
                app.PreparingsecondplotButton.Value=true;
                app.StartedButton.FontColor=[0,0,0];
                app.CalculatingnumberofBurstsButton.FontColor=[0,0,0];
                app.PreparingfirstplotButton.FontColor=[0,0,0];
                app.PreparingsecondplotButton.FontColor=[1,0,0];
                app.DoneButton.FontColor=[0,0,0];
                app.StartedButton.Enable=false;
                
                app.CalculatingnumberofBurstsButton.Enable=false;
                app.PreparingfirstplotButton.Enable=true;
                
                app.DoneButton.Enable=false;
                pause(1);
                
                if firstTime
                    [app.timeStamp,ia,~]=unique(app.Burst_Data.ts);
                    nbBurstChannels=zeros(length(app.Burst_Data.ts),1);
                    app.NbBurstChannels=zeros(length(app.timeStamp),1);
                    NtimeWindow=app.TimeWindowsecEditField.Value;
                    for i=1:(length(app.Burst.T_start))
                        
                        ind1=find(app.Burst_Data.ts==app.Burst.T_start(i));
                        ind2=find(app.Burst_Data.ts==app.Burst.T_end(i));
                        for count1=ind1(1):ind2(end)
                            nxtTimeStamp=app.Burst_Data.ts(count1)+NtimeWindow;
                            useIndex=find(app.Burst_Data.ts<=nxtTimeStamp);
                            if useIndex(end)>ind2(end)
                                nbBurstChannels(count1)=length(unique(app.Burst_Data.chan(count1:ind2(end))));
                            else
                                nbBurstChannels(count1)=length(unique(app.Burst_Data.chan(count1:useIndex(end))));
                            end
                        end
                    end
                    
                    app.OutputData5.SettingsStartTime=min(app.filteredData.ts);
                    app.OutputData5.SettingsEndTime=max(app.filteredData.ts);
                    app.OutputData5.SettingsSpikesPerBurst=app.SpikesBurstEditField.Value;
                    app.OutputData5.SettingsBurstWindowTime=app.TimeWindowsecEditField.Value;
                    
                    selectedVals=app.ExcludedChannelsListBox_2.Items;
                    if ~isempty(selectedVals)
                        newVals=[];
                        
                        
                        for i=1:length(selectedVals)
                            newVals=strcat(newVals,'/ ',selectedVals{i});
                            
                        end
                        
                        app.OutputData5.IncludeExcludedChannelsInAnalysis= newVals;
                    else
                        app.OutputData5.IncludeExcludedChannelsInAnalysis='No';
                    end
                    app.OutputData5.MinimumChannels=app.MinchannelsEditField.Value;
                    if app.EpisodeAnalysisCheckBox.Value
                        app.OutputData5.EpisodeAnalysis='Yes';
                        app.OutputData5.EpisodeISI=app.EpisodeISI.Value;
                        app.OutputData5.EpisodeMinWindow=app.EpisodeMin.Value;
                        app.OutputData5.EpisodeMaxWindow=app.EpisodeMax.Value;
                    else
                        app.OutputData5.EpisodeAnalysis='No';
                    end
                    app.OutputData6.ExcludedChannels=sort(app.excludedChannels)';
                    app.NbBurstChannels=nbBurstChannels(ia);
                end
                
                
                plot( app.timeStamp,app.NbBurstChannels,'k', 'linewidth', 1, 'Tag', 'allData','parent',app.FinalPlot);
                xlim(app.FinalPlot,[min(app.Burst_Data.ts) max(app.Burst_Data.ts)]);
                if isempty(app.Burst.C)
                    ylim(app.FinalPlot,[0 0.5]);
                else
                    ylim(app.FinalPlot,[0 max(app.Burst.C)]);
                end
                if firstTime
                    app.Slider.Visible='on';
                    app.Slider.Limits=double([app.Burst_Data.ts(1) app.Burst_Data.ts(end)]);
                    app.Slider.Value=double(app.Burst_Data.ts(1));
                    app.Slider.MinorTicks=double(app.Burst_Data.ts(2:end-1));
                    for i=1:length(app.Burst_Data.ts)
                        addItems(i)={num2str(app.Burst_Data.ts(i))};
                    end
                    set(app.SelectaTimestampDropDown, 'Items', addItems');
                end
                hold(app.BD,'off');
                hold(app.FinalPlot,'off')
                app.DoneButton.Enable=true;
                app.DoneButton.Value=true;
                app.StartedButton.FontColor=[0,0,0];
                app.CalculatingnumberofBurstsButton.FontColor=[0,0,0];
                app.PreparingfirstplotButton.FontColor=[0,0,0];
                app.PreparingsecondplotButton.FontColor=[0,0,0];
                app.DoneButton.FontColor=[1,0,0];
                
                app.DoneButton.Enable=false;
                app.StartedButton.Enable=false;
                app.CalculatingnumberofBurstsButton.Enable=false;
                app.PreparingfirstplotButton.Enable=false;
                app.PreparingsecondplotButton.Enable=false;
                
                pause(1);
                
            end
        end
        
    end


    methods (Access = public)
        
        function results = func4(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.BurstDetectionUIFigure.WindowState = 'maximized';
            app.BurstDetectionUIFigure.WindowState = 'normal';
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            
            [filename, pathname] = uigetfile({'*.mat'},'File Selector');
            app.BurstDetectionUIFigure.Visible = 'off';
            app.BurstDetectionUIFigure.Visible='on';
            filepathname=strcat(pathname,filename);
            app.DataFileEditField.Value=filepathname;
            app.ApplyButton.Enable=false;
        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
            app.Episode_plot.cla;
            app.SpikesEpisodePlot.cla;
            app.SavedEditField.Value=0;
            app.NbBurstsEditField.Value=0;
            if ~isempty(app.filteredData)
                app.StartedButton.Enable=false;
                app.CalculatingnumberofBurstsButton.Enable=false;
                app.PreparingfirstplotButton.Enable=false;
                app.PreparingsecondplotButton.Enable=false;
                app.DoneButton.Enable=false;
                app.StartedButton.FontColor=[0,0,0];
                app.CalculatingnumberofBurstsButton.FontColor=[0,0,0];
                app.PreparingfirstplotButton.FontColor=[0,0,0];
                app.PreparingsecondplotButton.FontColor=[0,0,0];
                app.DoneButton.FontColor=[0,0,0];
                
                pause(1);
                BurstMain(app);
            else
                f = errordlg('Please select a file first!','Error','error');
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
            set(app.BD, 'NextPlot', 'replacechildren');
            set(app.FinalPlot, 'NextPlot', 'replacechildren');
            
            app.Slider.Visible='on';
            value = app.Slider.Value;
            ind1=find(app.Burst_Data.ts>=value);
            if isempty(ind1)
                app.SelectaTimestampDropDown.Value={num2str(app.Burst_Data.ts(end))};
                app.Slider.Value=app.Burst_Data.ts(end);
                value=app.Burst_Data.ts(end);
            else
                app.SelectaTimestampDropDown.Value={num2str(app.Burst_Data.ts(ind1(1)))};
                app.Slider.Value=double(app.Burst_Data.ts(ind1(1)));
                value=app.Burst_Data.ts(ind1(1));
            end
            
            plotData(app,0)
            patch([value value+app.TimeWindowsecEditField.Value value+ app.TimeWindowsecEditField.Value value], [min(app.new_Data.chan) min(app.new_Data.chan) max(app.new_Data.chan) max(app.new_Data.chan)], 'cyan', 'EdgeColor','cyan','FaceAlpha', 0.2,'parent', app.BD) ;  % <— ADD THIS LINE
            patch([value value+app.TimeWindowsecEditField.Value value+ app.TimeWindowsecEditField.Value value], [0 0 max(app.new_Data.chan) max(app.new_Data.chan)], 'cyan','EdgeColor','cyan','FaceAlpha', 0.2,'parent', app.FinalPlot) ;  % <— ADD THIS LINE
            
            xlim(app.BD,[value-1 value+1]);
            xlim(app.FinalPlot,[value-1 value+1]);
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            set(app.BD, 'NextPlot', 'replacechildren');
            set(app.FinalPlot, 'NextPlot', 'replacechildren');
            plotData(app,1,1);
        end

        % Value changed function: SelectaTimestampDropDown
        function SelectaTimestampDropDownValueChanged(app, event)
            value = app.SelectaTimestampDropDown.Value;
            app.Slider.Visible='on';
            app.Slider.Value=str2double(value);
            SliderValueChanged(app, event);
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            app.SavedEditField.Value=0;
            app.NbBurstsEditField.Value=0;
            Data=app.filteredData;
            if ~isempty(Data)
                Data=sortrows(Data,[2 3]);
                hold(app.ISIN,'off')
                % Load Data into workspace and start here
                % Data is a table with 3 columns [ts (time-stamp), chan (eletrode channel),
                % sortcode (sort code that autospike sorter makes)
                [C, ia, ic] = unique(Data.ts);
                a_counts = accumarray(ic,1); %A(i)=sum(val(subs(:)==i))).
                value_counts = [C, a_counts];
                
                %find the ts in Data were more than two spikes occure at
                % exactly the same time and remove those spikes because those are most probably noise
                
                idx=find(value_counts(:,2)>2);
                app.new_Data=Data(~ismember(Data.ts,C(idx)),:);
                
                tmp=unique(app.new_Data(:,2:3));
                
                app.new_Data=sortrows(app.new_Data,[1 2 3]);
                SpikeTimes=app.new_Data.ts;
                Steps=10.^(app.StartEditField.Value:app.StepEditField.Value:app.EndEditField.Value);
                N=app.StartEditField_2.Value:app.StepEditField_2.Value:app.EndEditField_2.Value;
                
                map = hsv(length(N));
                cnt = 0;
                legendLabels={};
                min1=0;
                max1=0;
                
                for FRnum = N
                    cnt = cnt + 1;
                    ISI_N= SpikeTimes( FRnum:end ) - SpikeTimes( 1:end-(FRnum-1) );
                    n = histc( ISI_N*1000, Steps*1000 );
                    %n = smooth( n, 'lowess' );
                    plot( Steps*1000, n/sum(n), '.-', 'color', map(cnt,:) ,'parent',app.ISIN)
                    if min(n)<(min1/sum(n))
                        min1=min(n)/sum(n);
                    end
                    if max(n)>(max1/sum(n))
                        max1=max(n)/sum(n);
                    end
                    hold(app.ISIN,'on')
                    legendLabels{cnt}=strcat(num2str(N(cnt)),' spikes');
                end
                set(app.ISIN,'xscale','log')
                set(app.ISIN,'yscale','log')
                legend(app.ISIN,legendLabels)
                ylim(app.ISIN,[min1 max1]);
                xlim(app.ISIN,[Steps(1) Steps(end)]*1000);
                hold(app.ISIN,'off')
            else
                f = errordlg('Please select a file first!','Error','error');
            end
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            RunButton_2Pushed(app, event);
        end

        % Window button down function: BurstDetectionUIFigure
        function BurstDetectionUIFigureWindowButtonDown(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'allData')
                %Store the CurrentPiont of the UIAxes as app.a
                xvalue = app.FinalPlot.CurrentPoint(1);
                set(app.BD, 'NextPlot', 'replacechildren');
                set(app.FinalPlot, 'NextPlot', 'replacechildren');
                
                plotData(app,0,1)
                value = xvalue;
                ind1=find(app.Burst_Data.ts>=value);
                app.Slider.Visible='on';
                if isempty(ind1)
                    
                    app.SelectaTimestampDropDown.Value={num2str(app.Burst_Data.ts(end))};
                    app.Slider.Value=app.Burst_Data.ts(end);
                    value=app.Burst_Data.ts(end);
                else
                    app.SelectaTimestampDropDown.Value={num2str(app.Burst_Data.ts(ind1(1)))};
                    app.Slider.Value=double(app.Burst_Data.ts(ind1(1)));
                    value=app.Burst_Data.ts(ind1(1));
                end
                
                
                patch([value value+app.TimeWindowsecEditField.Value value+ app.TimeWindowsecEditField.Value value], [min(app.new_Data.chan) min(app.new_Data.chan) max(app.new_Data.chan) max(app.new_Data.chan)], 'cyan', 'EdgeColor','cyan','FaceAlpha', 0.2,'parent', app.BD) ;  % <— ADD THIS LINE
                patch([value value+app.TimeWindowsecEditField.Value value+ app.TimeWindowsecEditField.Value value], [0 0 max(app.new_Data.chan) max(app.new_Data.chan)], 'cyan','EdgeColor','cyan','FaceAlpha', 0.2,'parent', app.FinalPlot) ;  % <— ADD THIS LINE
                
                xlim(app.BD,[value-1 value+1]);
                xlim(app.FinalPlot,[value-1 value+1]);
                
                
            end
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            
            filter = {'*.xlsx'};
            [file, path] = uiputfile(filter,'Save Results As');
            if file==0
                app.SavedEditField.Value=0;
                return;
            end
            writetable(app.OutputData5,strcat(path,file),'Sheet','Settings');
            writetable(app.OutputData6,strcat(path,file),'Sheet','Excluded Channels');
            if app.EpisodeAnalysisCheckBox.Value
                writetable(app.OutputData7,strcat(path,file),'Sheet','Episode Analysis');
                
            end
            UniqueChannels=sort(unique(app.Burst_Data.chan));
            
            DetailsOfBurst=[];
            NbSpikesPerChannel=[];
            NtimeWindow=app.TimeWindowsecEditField.Value;
            for i=1:(length(app.Burst.T_start))
                ind1=find(app.Burst_Data.ts==app.Burst.T_start(i));
                ind2=find(app.Burst_Data.ts==app.Burst.T_end(i));
                AllBurstChannels=sort([app.Burst_Data.chan(ind1(1):ind2(end)); UniqueChannels]);
                [Mu,ib,ic] = unique(AllBurstChannels, 'rows', 'stable');           % Unique Values By Row, Retaining Original Order
                h = accumarray(ic, 1);                              % Count Occurrences
                NbSpikesPerChannel = [NbSpikesPerChannel;i*ones(length(Mu),1) Mu h-1];
                app.SavedEditField.Value=i;
                if mod(i,10)==0
                    pause(0.5);
                end
                for count1=ind1(1):ind2(end)
                    nxtTimeStamp=app.Burst_Data.ts(count1)+NtimeWindow;
                    useIndex=find(app.Burst_Data.ts<=nxtTimeStamp);
                    if useIndex(end)>ind2(end)
                        nbBurstChannels(count1)=length(unique(app.Burst_Data.chan(count1:ind2(end))));
                        nbBurstSpikes=sum(app.Burst_Data.Burst(count1:ind2(end)));
                        DetailsOfBurst=[DetailsOfBurst;i app.Burst_Data.ts(count1) app.Burst_Data.ts(ind2(end)) nbBurstSpikes nbBurstChannels(count1)];
                    else
                        nbBurstChannels(count1)=length(unique(app.Burst_Data.chan(count1:useIndex(end))));
                        nbBurstSpikes=sum(app.Burst_Data.Burst(count1:useIndex(end)));
                        %temp=[i app.Burst_Data.ts(count1) app.Burst_Data.ts(useIndex(end)) nbBurstSpikes nbBurstChannels(count1)];
                        %xlsappend(strcat(path,file),temp,'Sheet3');
                        DetailsOfBurst=[DetailsOfBurst;i app.Burst_Data.ts(count1) app.Burst_Data.ts(useIndex(end)) nbBurstSpikes nbBurstChannels(count1)];
                    end
                end
            end
            if (app.EpisodeAnalysisCheckBox.Value)
                app.OutputData1.AvgBurstDuration= mean(app.OutputData2(:,5).Duration);
                app.OutputData1.AvgNbSpikesperBurst=mean(app.OutputData2(:,6).NbSpikesForEachBurst);
                app.OutputData1.AvgNbChannelsForEachBurst=mean(app.OutputData2(:,8).NbChannelsForEachBurst);
                app.OutputData1.NbSpikesInBurst=sum(app.OutputData2(:,6).NbSpikesForEachBurst);
                
            else
                app.OutputData1.AvgBurstDuration= mean(app.OutputData2(:,4).Duration);
                app.OutputData1.AvgNbSpikesperBurst=mean(app.OutputData2(:,5).NbSpikesForEachBurst);
                app.OutputData1.AvgNbChannelsForEachBurst=mean(app.OutputData2(:,7).NbChannelsForEachBurst);
                app.OutputData1.NbSpikesInBurst=sum(app.OutputData2(:,5).NbSpikesForEachBurst);
                
            end
            app.OutputData1.NbSpikesOutBurst=length(app.new_Data.ts)-sum(app.Burst.S);
            if ~isempty(DetailsOfBurst)
                app.OutputData3.BurstID=DetailsOfBurst(:,1);
                
                app.OutputData3.StartWindow=DetailsOfBurst(:,2);
                app.OutputData3.EndWindow=DetailsOfBurst(:,3);
                app.OutputData3.nbSpikes=DetailsOfBurst(:,4);
                app.OutputData3.nbCh=DetailsOfBurst(:,5);
            end
            if ~isempty(NbSpikesPerChannel)
                burstIDVals=unique(NbSpikesPerChannel(:,1));
                channelIDVals=unique(NbSpikesPerChannel(:,2));
                k3=1;
                temp=zeros(length(burstIDVals),65);
                for k1=1:length(burstIDVals)
                    temp(k1+1,1)=burstIDVals(k1);
                    for k2=1:length(channelIDVals)
                        temp(1,channelIDVals(k2)+1)=channelIDVals(k2);
                        temp(k1+1,channelIDVals(k2)+1)= NbSpikesPerChannel(k3,3);
                        k3=k3+1;
                    end
                end
            end
            
            writetable(app.OutputData1,strcat(path,file),'Sheet',1);
            writetable(app.OutputData2,strcat(path,file),'Sheet',2);
            writetable(app.OutputData3,strcat(path,file),'Sheet',3);
            col_header={};
            col_headerEpisode={};
            col_header(1,1)={' '};
            col_header(1,2)={' '};
            col_headerEpisode(1,1)={' '};
            col_headerEpisode(1,2)={' '};
            if ~isempty(NbSpikesPerChannel)
                N=size(temp,1)-1;
                for cl=1:size(temp,2)
                    app.OutputData4(1:N,cl)=table(temp(2:end,cl));
                end
                col_header(1)={'BurstID'};
                col_headerEpisode(1)= {'EpisodeID'};
                
                for cl=2:size(temp,2)
                    col_header(cl)={strcat('ChannelID_',num2str(cl-1))};
                    col_headerEpisode(cl)={strcat('ChannelID_',num2str(cl-1))};
                end
                app.OutputData4.Properties.VariableNames = col_header;
                
                writetable(app.OutputData4,strcat(path,file),'Sheet','Channels_Bursts_Details');
                if app.EpisodeAnalysisCheckBox.Value
                    temp7=([[1:size(app.OutputData8,1)]' app.OutputData8 zeros(size(app.OutputData8,1),64-size(app.OutputData8,2))]);
                    temp8=table;
                    for c2=1:size(temp7,2)
                        temp8(:,c2)=table(temp7(:,c2));
                    end
                    temp8.Properties.VariableNames = col_headerEpisode;
                    
                    writetable(temp8,strcat(path,file),'Sheet','Channels_Episode_Details');
                end
            end
            
            f = msgbox('Results Saved!','Success');
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            selectedVals=app.ChannelsusedforBurstDetectionListBox.Value;
            if ~isempty(selectedVals)
                prevVals=app.ChannelsnotusedforBurstDetectionListBox.Items;
                prevVals=[prevVals selectedVals];
                newVals=[];
                for i=1:length(prevVals)
                    newVals(i)=str2num(prevVals{i});
                end
                newVals=sort(newVals);
                newValsCell={};
                for  i=1:length(newVals)
                    newValsCell(i)={num2str(newVals(i))};
                end
                app.ChannelsnotusedforBurstDetectionListBox.Items=newValsCell;
                [indices]=ismember(app.ChannelsusedforBurstDetectionListBox.Items,selectedVals);
                app.ChannelsusedforBurstDetectionListBox.Items(indices==1)=[];
            end
            
        end

        % Button pushed function: RunButton_4
        function RunButton_4Pushed(app, event)
            %reser values of all tabs
            
            
            filepathname=strcat(app.DataFileEditField.Value);
            
            if ~isempty(filepathname)
                app.Slider.Visible='off';
                app.SelectaTimestampDropDown.Items = {'TimeStamp'};
                set(app.SelectaTimestampDropDown, 'Items', {});
                
                app.ISIN.cla;
                app.UIAxes.cla;
                app.BD.cla;
                app.FinalPlot.cla;
                app.Episode_plot.cla;
                app.SpikesEpisodePlot.cla;
                colorbar(app.UIAxes,'visible','off')
                app.SpikesBurstEditField.Value = 10;
                app.TimeWindowsecEditField.Value = 1;
                
                app.NbBurstsEditField.Value = 0;
                app.SavedEditField.Value = 0;
                app.ExcludedChannelsListBox_2.Items={};
                
                app.StartedButton.Enable=false;
                app.StartedButton.Value=true;
                app.StartedButton.FontColor=[0,0,0];
                app.CalculatingnumberofBurstsButton.FontColor=[0,0,0];
                app.PreparingfirstplotButton.FontColor=[0,0,0];
                app.PreparingsecondplotButton.FontColor=[0,0,0];
                app.DoneButton.FontColor=[0,0,0];
                app.CalculatingnumberofBurstsButton.Enable=false;
                app.PreparingfirstplotButton.Enable=false;
                app.PreparingsecondplotButton.Enable=false;
                app.DoneButton.Enable=false;
                
                app.excludedChannels=[];
                app.StartEditField_3.Value=0;
                app.EndEditField_3.Value=0;
                app.ChannelsusedforBurstDetectionListBox.Items={};
                app.ChannelsnotusedforBurstDetectionListBox.Items={};
                app.ApplyButton.Enable=true;
                mydata=load(filepathname);
                F = fieldnames(mydata);
                mydata = RenameField(mydata, F, {'dataStruct'});
                dataStruct=mydata.dataStruct;
                
                app.GlobalData=table;
                app.GlobalData.ts=single(dataStruct.snips.eNe1.ts);
                app.GlobalData.chan=single(dataStruct.snips.eNe1.chan);
                app.GlobalData.sortcode=single(dataStruct.snips.eNe1.sortcode);
                [C, ia, ic] = unique(app.GlobalData.ts);
                a_counts = accumarray(ic,1); %A(i)=sum(val(subs(:)==i))). it gives back how many spikes are there at each timestamp
                value_counts = [C, a_counts];
                
                %find the ts in Data were more than two spikes were reported at exactly the same time
                % and remove those spikes cz most probably they are noise
                idx=find(value_counts(:,2)>2);
                
                app.GlobalData=app.GlobalData(~ismember(app.GlobalData.ts,C(idx)),:);
                
                
                app.StartEditField_3.Value=double(min(app.GlobalData.ts));
                app.EndEditField_3.Value=double(max(app.GlobalData.ts));
                channels=sort(unique(app.GlobalData.chan));
                for i=1:length(channels)
                    channelsItems(i)={num2str(channels(i))};
                end
                
                app.ChannelsusedforBurstDetectionListBox.Items=channelsItems;
                app.filteredData=app.GlobalData;
                app.filteredData_All=app.GlobalData;
                plot(app.GlobalData.ts,app.GlobalData.chan, 'k.','parent',app.BD_2);
                ylim(app.BD_2,[min(app.GlobalData.chan) max(app.GlobalData.chan)]);
                xlim(app.BD_2,[min(app.GlobalData.ts) max(app.GlobalData.ts)]);
                hold(app.BD_2,'off');
            else
                f = errordlg('Please select a file first!','Error','error');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            selectedVals=app.ChannelsnotusedforBurstDetectionListBox.Value;
            if ~isempty(selectedVals)
                prevVals=app.ChannelsusedforBurstDetectionListBox.Items;
                prevVals=[prevVals selectedVals];
                newVals=[];
                for i=1:length(prevVals)
                    newVals(i)=str2num(prevVals{i});
                end
                newVals=sort(newVals);
                newValsCell={};
                for  i=1:length(newVals)
                    newValsCell(i)={num2str(newVals(i))};
                end
                app.ChannelsusedforBurstDetectionListBox.Items=newValsCell;
                [indices]=ismember(app.ChannelsnotusedforBurstDetectionListBox.Items,selectedVals);
                app.ChannelsnotusedforBurstDetectionListBox.Items(indices==1)=[];
                
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            app.ISIN.cla;
            app.UIAxes.cla;
            app.BD.cla;
            app.FinalPlot.cla;
            app.Episode_plot.cla;
            app.SpikesEpisodePlot.cla;
            colorbar(app.UIAxes,'visible','off')
            app.SpikesBurstEditField.Value = 10;
            app.TimeWindowsecEditField.Value = 1;
            
            app.NbBurstsEditField.Value = 0;
            app.SavedEditField.Value = 0;
            
            app.StartedButton.Enable=false;
            app.StartedButton.Value=true;
            app.StartedButton.FontColor=[0,0,0];
            app.CalculatingnumberofBurstsButton.FontColor=[0,0,0];
            app.PreparingfirstplotButton.FontColor=[0,0,0];
            app.PreparingsecondplotButton.FontColor=[0,0,0];
            app.DoneButton.FontColor=[0,0,0];
            app.CalculatingnumberofBurstsButton.Enable=false;
            app.PreparingfirstplotButton.Enable=false;
            app.PreparingsecondplotButton.Enable=false;
            app.DoneButton.Enable=false;


            app.SavedEditField.Value=0;
            app.NbBurstsEditField.Value=0;
            app.filteredData=app.GlobalData;
            app.filteredData_All=app.GlobalData;
            startTimeStamp= app.StartEditField_3.Value;
            endTimeStamp= app.EndEditField_3.Value;
            app.excludedChannels=[];
            ind1=find(app.filteredData.ts<startTimeStamp);
            if ~isempty(ind1)
                app.filteredData(ind1,:)=[];
                app.filteredData_All(ind1,:)=[];
            end
            
            ind1=find(app.filteredData.ts>endTimeStamp);
            if ~isempty(ind1)
                app.filteredData(ind1,:)=[];
                app.filteredData_All(ind1,:)=[];
            end
            
            prevVals=[app.ChannelsnotusedforBurstDetectionListBox.Items app.ExcludedChannelsListBox_2.Items];
            
            newVals=[];
            for i=1:length(prevVals)
                newVals(i)=str2num(prevVals{i});
                
                ind1=find(app.filteredData.chan==newVals(i));
                if ~isempty(ind1)
                    app.filteredData(ind1,:)=[];
                end
            end
            app.excludedChannels=newVals;
            plot(app.filteredData.ts,app.filteredData.chan, 'k.','parent',app.BD_2);
            ylim(app.BD_2,[min(app.filteredData.chan) max(app.filteredData.chan)]);
            xlim(app.BD_2,[min(app.filteredData.ts) max(app.filteredData.ts)]);
            
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            UniqueChannels=sort(unique(app.Burst_Data.chan));
            
            DetailsOfBurst=[];
            NbSpikesPerChannel=[];
            NtimeWindow=app.TimeWindowsecEditField.Value;
            for i=1:(length(app.Burst.T_start))
                ind1=find(app.Burst_Data.ts==app.Burst.T_start(i));
                ind2=find(app.Burst_Data.ts==app.Burst.T_end(i));
                AllBurstChannels=sort([app.Burst_Data.chan(ind1(1):ind2(end)); UniqueChannels]);
                [Mu,ib,ic] = unique(AllBurstChannels, 'rows', 'stable');           % Unique Values By Row, Retaining Original Order
                h = accumarray(ic, 1);                              % Count Occurrences
                NbSpikesPerChannel = [NbSpikesPerChannel;i*ones(length(Mu),1) Mu h-1];
            end
            if ~isempty(NbSpikesPerChannel)
                burstIDVals=unique(NbSpikesPerChannel(:,1));
                channelIDVals=unique(NbSpikesPerChannel(:,2));
                k3=1;
                temp=[];
                for k1=1:length(burstIDVals)
                    temp(k1+1,1)=burstIDVals(k1);
                    for k2=1:length(channelIDVals)
                        temp(1,k2+1)=channelIDVals(k2);
                        temp(k1+1,k2+1)= NbSpikesPerChannel(k3,3);
                        k3=k3+1;
                    end
                end
                b= bar3(channelIDVals,temp(2:end,2:end)','detached','parent',app.UIAxes );
                %            surf(burstIDVals,channelIDVals,temp(2:end,2:end)','parent',app.UIAxes );
                
                for k = 1:length(b)
                    zdata = b(k).ZData;
                    b(k).CData = zdata;
                    b(k).FaceColor = 'interp';
                    % b(k).EdgeColor = 'none';
                end
                app.MinColorbarValueEditField_2.Value=0;
                app.MaxColorbarValueEditField_2.Value=max(max(temp(2:end,2:end)));
                
                
                caxis(app.UIAxes,[0 max(max(temp(2:end,2:end)))]);
                cmap =  colormap(app.UIAxes,parula) ;   % change color map
                Nrepeats=floor(max(max(temp(2:end,2:end)))/size(cmap,1));
                remaining=mod(max(max(temp(2:end,2:end))),size(cmap,1));
                if Nrepeats>1
                    cnt1=2;
                    
                    for iter=2:size(cmap,1)
                        cmap2(cnt1:(cnt1+Nrepeats),:)=repmat(cmap(iter,:),Nrepeats+1,1);
                        cnt1=cnt1+Nrepeats+1;
                    end
                    cmap2(end+1:end+remaining+1,:)=repmat(cmap(end,:),remaining+1,1);
                end
                
                cmap1(1,:)=[160 160 160]/256;
                if Nrepeats>1
                    cmap1(2:size(cmap2,1),:)=cmap2(2:end,:);
                else
                    cmap1(2:size(cmap,1),:)=cmap(2:end,:);
                    cmap1(end+1:end+remaining+1,:)=repmat(cmap(end,:),remaining+1,1);
                end
                colormap(app.UIAxes,cmap1);
                colorbar(app.UIAxes);
            else
                app.UIAxes.reset
            end
        end

        % Value changed function: EpisodeAnalysisCheckBox
        function EpisodeAnalysisCheckBoxValueChanged(app, event)
            value = app.EpisodeAnalysisCheckBox.Value;
            if value
                
                app.EpisodeMin.Enable=true;
                app.EpisodeMax.Enable=true;
                app.WindowsizesecLabel.Enable=true;
                app.Label.Enable=true;
                app.MaxinterspikesLabel.Enable=true;
                app.WindowsizesecLabel.Enable=true;
                app.EpisodeISI.Enable=true;
            else
                
                app.EpisodeMin.Enable=false;
                app.EpisodeMax.Enable=false;
                app.WindowsizesecLabel.Enable=false;
                app.Label.Enable=false;
                
                app.MaxinterspikesLabel.Enable=false;
                app.WindowsizesecLabel.Enable=false;
                app.EpisodeISI.Enable=false;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            selectedVals=app.ChannelsnotusedforBurstDetectionListBox.Value;
            if ~isempty(selectedVals)
                prevVals=app.ExcludedChannelsListBox_2.Items;
                prevVals=[prevVals selectedVals];
                newVals=[];
                for i=1:length(prevVals)
                    newVals(i)=str2num(prevVals{i});
                end
                newVals=sort(newVals);
                newValsCell={};
                for  i=1:length(newVals)
                    newValsCell(i)={num2str(newVals(i))};
                end
                app.ExcludedChannelsListBox_2.Items=newValsCell;
                [indices]=ismember(app.ChannelsnotusedforBurstDetectionListBox.Items,selectedVals);
                app.ChannelsnotusedforBurstDetectionListBox.Items(indices==1)=[];
            end
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            selectedVals=app.ExcludedChannelsListBox_2.Value;
            if ~isempty(selectedVals)
                prevVals=app.ChannelsnotusedforBurstDetectionListBox.Items;
                prevVals=[prevVals selectedVals];
                newVals=[];
                for i=1:length(prevVals)
                    newVals(i)=str2num(prevVals{i});
                end
                newVals=sort(newVals);
                newValsCell={};
                for  i=1:length(newVals)
                    newValsCell(i)={num2str(newVals(i))};
                end
                app.ChannelsnotusedforBurstDetectionListBox.Items=newValsCell;
                [indices]=ismember(app.ExcludedChannelsListBox_2.Items,selectedVals);
                app.ExcludedChannelsListBox_2.Items(indices==1)=[];
                
            end
        end

        % Value changed function: MinColorbarValueEditField
        function MinColorbarValueEditFieldValueChanged(app, event)
            value1 = app.MinColorbarValueEditField.Value;
            value2= app.MaxColorbarValueEditField.Value;
            caxis(app.SpikesEpisodePlot,[value1 value2]);
        end

        % Value changed function: MaxColorbarValueEditField
        function MaxColorbarValueEditFieldValueChanged(app, event)
            
            value1 = app.MinColorbarValueEditField.Value;
            value2= app.MaxColorbarValueEditField.Value;
            caxis(app.SpikesEpisodePlot,[value1 value2]);
        end

        % Value changed function: MinColorbarValueEditField_2
        function MinColorbarValueEditField_2ValueChanged(app, event)
            value1 = app.MinColorbarValueEditField_2.Value;
            value2= app.MaxColorbarValueEditField_2.Value;
            caxis(app.UIAxes,[value1 value2]);
        end

        % Value changed function: MaxColorbarValueEditField_2
        function MaxColorbarValueEditField_2ValueChanged(app, event)
            value1 = app.MinColorbarValueEditField_2.Value;
            value2= app.MaxColorbarValueEditField_2.Value;
            caxis(app.UIAxes,[value1 value2]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BurstDetectionUIFigure = uifigure('Visible', 'off');
            app.BurstDetectionUIFigure.Name = 'Burst Detection';
            app.BurstDetectionUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @BurstDetectionUIFigureWindowButtonDown, true);
            app.TabGroup = uitabgroup(app.BurstDetectionUIFigure);
            app.FileSelectionTab = uitab(app.TabGroup);
            app.FileSelectionTab.Title = 'File Selection';
            app.BD_2 = uiaxes(app.FileSelectionTab);
            title(app.BD_2, 'Burst Detection Data')
            xlabel(app.BD_2, 'Timestamp (sec)')
            ylabel(app.BD_2, 'Channels')
            app.TimeWindowsecPanel = uipanel(app.FileSelectionTab);
            app.TimeWindowsecPanel.Title = 'Time Window (sec)';
            app.StartEditField_3Label = uilabel(app.TimeWindowsecPanel);
            app.StartEditField_3Label.Text = 'Start ';
            app.StartEditField_3 = uieditfield(app.TimeWindowsecPanel, 'numeric');
            app.EndEditField_3Label = uilabel(app.TimeWindowsecPanel);
            app.EndEditField_3Label.Text = 'End';
            app.EndEditField_3 = uieditfield(app.TimeWindowsecPanel, 'numeric');
            app.FileSelectionPanel = uipanel(app.FileSelectionTab);
            app.FileSelectionPanel.Title = 'File Selection';
            app.RunButton_4 = uibutton(app.FileSelectionPanel, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_4Pushed, true);
            app.RunButton_4.Text = 'Run';
            app.DataFileEditField = uieditfield(app.FileSelectionPanel, 'text');
            app.SelectFileButton = uibutton(app.FileSelectionPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.ApplyButton = uibutton(app.FileSelectionTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.ChannelsusedforBurstDetectionLabel = uilabel(app.FileSelectionTab);
            app.ChannelsusedforBurstDetectionLabel.Text = {'Channels used'; 'for Burst Detection'};
            app.ChannelsusedforBurstDetectionListBox = uilistbox(app.FileSelectionTab);
            app.ChannelsusedforBurstDetectionListBox.Items = {};
            app.ChannelsusedforBurstDetectionListBox.Multiselect = 'on';
            app.ChannelsusedforBurstDetectionListBox.Value = {};
            app.ChannelsnotusedforBurstDetectionListBoxLabel = uilabel(app.FileSelectionTab);
            app.ChannelsnotusedforBurstDetectionListBoxLabel.Text = {'Channels not used '; 'for Burst Detection'};
            app.ChannelsnotusedforBurstDetectionListBox = uilistbox(app.FileSelectionTab);
            app.ChannelsnotusedforBurstDetectionListBox.Items = {};
            app.ChannelsnotusedforBurstDetectionListBox.Multiselect = 'on';
            app.ChannelsnotusedforBurstDetectionListBox.Value = {};
            app.Button = uibutton(app.FileSelectionTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>>';
            app.Button_2 = uibutton(app.FileSelectionTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<<';
            app.Button_3 = uibutton(app.FileSelectionTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '>>';
            app.Button_4 = uibutton(app.FileSelectionTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '<<';
            app.ChannelsIncludedinresultsLabel = uilabel(app.FileSelectionTab);
            app.ChannelsIncludedinresultsLabel.Text = {'Channels '; 'Included in results'};
            app.ExcludedChannelsListBox_2 = uilistbox(app.FileSelectionTab);
            app.ExcludedChannelsListBox_2.Items = {};
            app.ExcludedChannelsListBox_2.Multiselect = 'on';
            app.ExcludedChannelsListBox_2.Value = {};
            app.WindowSizeTab = uitab(app.TabGroup);
            app.WindowSizeTab.Title = 'Window Size';
            app.ISIN = uiaxes(app.WindowSizeTab);
            title(app.ISIN, 'Window Threshold Selection')
            xlabel(app.ISIN, 'ISI, T_i - T_{i-(N-1) _{ }} (ms)')
            ylabel(app.ISIN, 'Probability (%)')
            app.RunButton_2 = uibutton(app.WindowSizeTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.Steps10sPanel = uipanel(app.WindowSizeTab);
            app.Steps10sPanel.Title = 'Steps (10^ s)';
            app.StartEditFieldLabel = uilabel(app.Steps10sPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Steps10sPanel, 'numeric');
            app.StartEditField.Value = -5;
            app.StepEditFieldLabel = uilabel(app.Steps10sPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.Steps10sPanel, 'numeric');
            app.StepEditField.Value = 0.05;
            app.EndEditFieldLabel = uilabel(app.Steps10sPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.Steps10sPanel, 'numeric');
            app.EndEditField.Value = 1.5;
            app.MinSpikeswindowPanel = uipanel(app.WindowSizeTab);
            app.MinSpikeswindowPanel.Title = 'Min. Spikes/window';
            app.StartEditField_2Label = uilabel(app.MinSpikeswindowPanel);
            app.StartEditField_2Label.Text = 'Start';
            app.StartEditField_2 = uieditfield(app.MinSpikeswindowPanel, 'numeric');
            app.StartEditField_2.Value = 2;
            app.StepEditField_2Label = uilabel(app.MinSpikeswindowPanel);
            app.StepEditField_2Label.Text = 'Step';
            app.StepEditField_2 = uieditfield(app.MinSpikeswindowPanel, 'numeric');
            app.StepEditField_2.Value = 1;
            app.EndEditField_2Label = uilabel(app.MinSpikeswindowPanel);
            app.EndEditField_2Label.Text = 'End';
            app.EndEditField_2 = uieditfield(app.MinSpikeswindowPanel, 'numeric');
            app.EndEditField_2.Value = 10;
            app.ResetButton_2 = uibutton(app.WindowSizeTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.BurstDetectionPlotTab = uitab(app.TabGroup);
            app.BurstDetectionPlotTab.Title = 'Burst Detection Plot';
            app.BD = uiaxes(app.BurstDetectionPlotTab);
            title(app.BD, 'Burst Detection Data')
            xlabel(app.BD, 'Timestamp (sec)')
            ylabel(app.BD, 'Channels')
            app.FinalPlot = uiaxes(app.BurstDetectionPlotTab);
            title(app.FinalPlot, 'Average Burst Detection Data')
            xlabel(app.FinalPlot, 'Timestamp (sec)')
            ylabel(app.FinalPlot, 'Nb of Channels in Burst Mode')
            app.Slider = uislider(app.BurstDetectionPlotTab);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SelectaTimestampDropDown = uidropdown(app.BurstDetectionPlotTab);
            app.SelectaTimestampDropDown.Items = {'TimeStamp'};
            app.SelectaTimestampDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectaTimestampDropDownValueChanged, true);
            app.SelectaTimestampDropDown.Value = 'TimeStamp';
            app.ResetButton = uibutton(app.BurstDetectionPlotTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BurstCriteriaPanel = uipanel(app.BurstDetectionPlotTab);
            app.BurstCriteriaPanel.Title = 'Burst Criteria';
            app.SpikesBurstEditFieldLabel = uilabel(app.BurstCriteriaPanel);
            app.SpikesBurstEditFieldLabel.Text = 'Spikes/Burst ';
            app.SpikesBurstEditField = uieditfield(app.BurstCriteriaPanel, 'numeric');
            app.SpikesBurstEditField.Value = 10;
            app.TimeWindowsecEditFieldLabel = uilabel(app.BurstCriteriaPanel);
            app.TimeWindowsecEditFieldLabel.Text = 'Time Window (sec)';
            app.TimeWindowsecEditField = uieditfield(app.BurstCriteriaPanel, 'numeric');
            app.TimeWindowsecEditField.Value = 1;
            app.MinchannelsEditFieldLabel = uilabel(app.BurstCriteriaPanel);
            app.MinchannelsEditFieldLabel.Text = 'Min channels';
            app.MinchannelsEditField = uieditfield(app.BurstCriteriaPanel, 'numeric');
            app.MinchannelsEditField.Value = 10;
            app.RunButton_3 = uibutton(app.BurstDetectionPlotTab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.SaveResultsButton = uibutton(app.BurstDetectionPlotTab, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = {'Save '; 'Results'};
            app.NbBurstsEditFieldLabel = uilabel(app.BurstDetectionPlotTab);
            app.NbBurstsEditFieldLabel.Text = 'Nb Bursts';
            app.NbBurstsEditField = uieditfield(app.BurstDetectionPlotTab, 'numeric');
            app.NbBurstsEditField.Editable = 'off';
            app.SavedEditFieldLabel = uilabel(app.BurstDetectionPlotTab);
            app.SavedEditFieldLabel.Text = 'Saved ';
            app.SavedEditField = uieditfield(app.BurstDetectionPlotTab, 'numeric');
            app.SavedEditField.Editable = 'off';
            app.ProcessingStepsButtonGroup = uibuttongroup(app.BurstDetectionPlotTab);
            app.ProcessingStepsButtonGroup.Title = 'Processing Steps';
            app.StartedButton = uiradiobutton(app.ProcessingStepsButtonGroup);
            app.StartedButton.Enable = 'off';
            app.StartedButton.Text = 'Started';
            app.StartedButton.Value = true;
            app.CalculatingnumberofBurstsButton = uiradiobutton(app.ProcessingStepsButtonGroup);
            app.CalculatingnumberofBurstsButton.Enable = 'off';
            app.CalculatingnumberofBurstsButton.Text = 'Calculating number of Bursts';
            app.PreparingfirstplotButton = uiradiobutton(app.ProcessingStepsButtonGroup);
            app.PreparingfirstplotButton.Enable = 'off';
            app.PreparingfirstplotButton.Text = 'Preparing first plot';
            app.PreparingsecondplotButton = uiradiobutton(app.ProcessingStepsButtonGroup);
            app.PreparingsecondplotButton.Enable = 'off';
            app.PreparingsecondplotButton.Text = 'Preparing second plot';
            app.DoneButton = uiradiobutton(app.ProcessingStepsButtonGroup);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done!';
            app.Panel = uipanel(app.BurstDetectionPlotTab);
            app.EpisodeMin = uieditfield(app.Panel, 'numeric');
            app.EpisodeMin.Value = 20;
            app.WindowsizesecLabel = uilabel(app.Panel);
            app.WindowsizesecLabel.Text = {'Window'; 'size (sec)'};
            app.Label = uilabel(app.Panel);
            app.Label.Text = '-';
            app.minmaxLabel = uilabel(app.Panel);
            app.minmaxLabel.Enable = 'off';
            app.minmaxLabel.Text = 'min   -  max';
            app.MaxinterspikesLabel = uilabel(app.Panel);
            app.MaxinterspikesLabel.Text = 'Max inter-spikes';
            app.EpisodeISI = uieditfield(app.Panel, 'numeric');
            app.EpisodeISI.Value = 80;
            app.EpisodeMax = uieditfield(app.Panel, 'numeric');
            app.EpisodeMax.Value = 100;
            app.EpisodeAnalysisCheckBox = uicheckbox(app.Panel);
            app.EpisodeAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @EpisodeAnalysisCheckBoxValueChanged, true);
            app.EpisodeAnalysisCheckBox.Text = {'Episode'; 'Analysis'};
            app.EpisodeAnalysisCheckBox.Value = true;
            app.Episode = uitab(app.TabGroup);
            app.Episode.Title = 'Episode Analysis';
            app.Episode_plot = uiaxes(app.Episode);
            title(app.Episode_plot, 'Episode Detection')
            xlabel(app.Episode_plot, 'Time (sec)')
            ylabel(app.Episode_plot, 'Channels')
            app.SpikesEpisodePlot = uiaxes(app.Episode);
            title(app.SpikesEpisodePlot, 'Spikes/Channel/Episode')
            xlabel(app.SpikesEpisodePlot, 'Episodes')
            ylabel(app.SpikesEpisodePlot, 'Channels')
            app.MinColorbarValueEditFieldLabel = uilabel(app.Episode);
            app.MinColorbarValueEditFieldLabel.Text = 'Min Colorbar Value';
            app.MinColorbarValueEditField = uieditfield(app.Episode, 'numeric');
            app.MinColorbarValueEditField.ValueChangedFcn = createCallbackFcn(app, @MinColorbarValueEditFieldValueChanged, true);
            app.MaxColorbarValueEditFieldLabel = uilabel(app.Episode);
            app.MaxColorbarValueEditFieldLabel.Text = 'Max Colorbar Value';
            app.MaxColorbarValueEditField = uieditfield(app.Episode, 'numeric');
            app.MaxColorbarValueEditField.ValueChangedFcn = createCallbackFcn(app, @MaxColorbarValueEditFieldValueChanged, true);
            app.DPlotTab = uitab(app.TabGroup);
            app.DPlotTab.Title = '3-D Plot';
            app.UIAxes = uiaxes(app.DPlotTab);
            xlabel(app.UIAxes, 'Burst ID')
            ylabel(app.UIAxes, 'Chanel ID')
            app.PlotButton = uibutton(app.DPlotTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MinColorbarValueEditFieldLabel_2 = uilabel(app.DPlotTab);
            app.MinColorbarValueEditFieldLabel_2.Text = 'Min Colorbar Value';
            app.MinColorbarValueEditField_2 = uieditfield(app.DPlotTab, 'numeric');
            app.MinColorbarValueEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinColorbarValueEditField_2ValueChanged, true);
            app.MaxColorbarValueEditFieldLabel_2 = uilabel(app.DPlotTab);
            app.MaxColorbarValueEditFieldLabel_2.Text = 'Max Colorbar Value';
            app.MaxColorbarValueEditField_2 = uieditfield(app.DPlotTab, 'numeric');
            app.MaxColorbarValueEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxColorbarValueEditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.BurstDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurstDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BurstDetectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BurstDetectionUIFigure)
        end
    end
end